classdef NCF_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        lblArea                 matlab.ui.control.Label
        TotalCoverageAreaLabel  matlab.ui.control.Label
        BranchBFailureButton    matlab.ui.control.Button
        TotalnumberofHopsLabel  matlab.ui.control.Label
        lblHops                 matlab.ui.control.Label
        BranchAFailureButton    matlab.ui.control.Button
        LoadNodesTableButton    matlab.ui.control.Button
        LoadNodesButton         matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        nodes; % Load Nodes Geomtrical Data
        table; % Load Nodes Geomtrical Data
        NodesOk = 0;
        TableOk = 0;
        area = [];
    end
    
    methods (Access = public)
        
        function createNodes(app)
            %--------------------------------------

            NodeData =  app.nodes.NodeData;
            hold(app.UIAxes,'on')
            for i = 1:size(NodeData,1)
                x= NodeData{i,3};
                y = 200- NodeData{i,4};
                r = 15;%NodeData{i,6};
                color = NodeData{i,5};
                Xg(i) = (100-x)/70;
                Yg(i)= (100-y)/70;
                z(i) = 0;
              % viscircles([x, y], r);
              c = [x y];
            app.area(i) = pi*r.^2;
            pos = [c-r 2*r 2*r];
           rectangle(app.UIAxes,'Position',pos,'Curvature',[1 1], 'FaceColor', color, 'Edgecolor','none');
           % axis equal
%             xlim([0 250])
%             ylim([0 250])         
            %   str = {NodeData{i,2},' ',num2str(x),' ',num2str(y)};
                plot(app.UIAxes,x, y, '.', 'Color', 'black');
            %   
            end
        end

        function createEdges(app)
          clear G;
          G = graph;
          nodeIP = {};
          nodeTemp={};
          nodeHops = [];
          TotalActiveNodeHops = 0;
          TotalArea = 0;
            NodeData =  app.nodes.NodeData;
            tableData = app.table.tableData;


            % Add edges to the graph
            for i = 1:size(tableData,1)
               nodeTemp = strsplit(tableData{i,1}, ', ');
               nodeIP{i} = nodeTemp{1};
                edgeCount = 0;
                %node = tableData{i,1};
                neighbors = strsplit(tableData{i,2}, ', ');
                if (length(neighbors)>=2)
                    for j = 1:length(neighbors)-1
                        node = neighbors{j};
                        neighbor = neighbors{j+1};
                        %if (~strcmp (neighbor,'10.0.0.14') && ~strcmp (node,'10.0.0.14'))
                            G = addedge(G,node,neighbor);
                    end
                 TotalArea = TotalArea + app.area(i);
                else
                    node = neighbors{1};
                    G = addedge(G,node,node); 
                end
            end
            
            for i = 1:size(NodeData,1)
              
              x= NodeData{i,3};
              y = 200- NodeData{i,4};
                          %  text(x,y,NodeData{i,2});
              NodePos = findnode(G,NodeData{i,2});
              Xg(NodePos) = x;
              Yg(NodePos) = y;
              z(NodePos) = 0;
              nodeHops(i) = length(shortestpath(G,findnode(G,NodeData{i,2}),findnode(G,'10.0.0.1')));
            
            end
  %h=plot(G,'XData',xg,'YData',yg);

    %   hold(app.UIAxes,'off')
    
   p1 = plot(app.UIAxes,G,'XData',Xg,'YData',Yg,'ZData',z,'MarkerSize',5,'NodeColor','k','EdgeAlpha',1,'EdgeColor','k');
   path = shortestpath(G,findnode(G,'10.0.0.12'),findnode(G,'10.0.0.1'));
   highlight(p1,path,'EdgeColor','g');

for i = 1:size(tableData,1)-2
   % fprintf("Node IP address: %s, Hop Count: %d  \n", nodeIP{i},  nodeHops(i));
      if (NodeData{i,5} == "#317DFA")
       TotalActiveNodeHops = TotalActiveNodeHops + nodeHops(i);
    end

end
app.lblHops.Text = num2str(TotalActiveNodeHops);
app.lblArea.Text = num2str(round(TotalArea/1000,2)) + " Km";

            %-------------------------------------
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadNodesButton
        function LoadNodesButtonPushed(app, event)
              evalin('base','CreateNodesPosition')
              app.nodes = load('nodes.mat','NodeData');
            cla(app.UIAxes) 
            createNodes(app);
        end

        % Button pushed function: LoadNodesTableButton
        function LoadNodesTableButtonPushed(app, event)
        evalin('base','CreateTable_Full')
        app.table = load('table.mat','tableData');
        cla(app.UIAxes) 
        createNodes(app);
        createEdges(app);
        end

        % Callback function: not associated with a component
        function RunButtonPushed(app, event)
        app.createNodes();
        end

        % Button pushed function: BranchAFailureButton
        function BranchAFailureButtonPushed(app, event)

        evalin('base','CreateTable_Branch_A_Failure')
        app.table = load('table.mat','tableData');
        cla(app.UIAxes) 
        createNodes(app);
        createEdges(app);   
        end

        % Button pushed function: BranchBFailureButton
        function BranchBFailureButtonPushed(app, event)
            evalin('base','CreateTable_Branch_B_Failure')
            app.table = load('table.mat','tableData');
            cla(app.UIAxes) 
            createNodes(app);
            createEdges(app);   
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {483, 483};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {170, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadNodesButton = uibutton(app.LeftPanel, 'push');
            app.LoadNodesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNodesButtonPushed, true);
            app.LoadNodesButton.Text = 'Load Nodes ';
            app.LoadNodesTableButton = uibutton(app.LeftPanel, 'push');
            app.LoadNodesTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNodesTableButtonPushed, true);
            app.LoadNodesTableButton.Text = 'Load Nodes Table';
            app.BranchAFailureButton = uibutton(app.LeftPanel, 'push');
            app.BranchAFailureButton.ButtonPushedFcn = createCallbackFcn(app, @BranchAFailureButtonPushed, true);
            app.BranchAFailureButton.Text = 'Branch A Failure';
            app.lblHops = uilabel(app.LeftPanel);
            app.lblHops.Text = '0';
            app.TotalnumberofHopsLabel = uilabel(app.LeftPanel);
            app.TotalnumberofHopsLabel.Text = 'Total number of Hops';
            app.BranchBFailureButton = uibutton(app.LeftPanel, 'push');
            app.BranchBFailureButton.ButtonPushedFcn = createCallbackFcn(app, @BranchBFailureButtonPushed, true);
            app.BranchBFailureButton.Text = 'Branch B Failure';
            app.TotalCoverageAreaLabel = uilabel(app.LeftPanel);
            app.TotalCoverageAreaLabel.Text = 'Total Coverage Area';
            app.lblArea = uilabel(app.LeftPanel);
            app.lblArea.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'WSN - NCF Tool')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-20 220];
            app.UIAxes.YLim = [-20 220];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NCF_Tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end